classdef Out < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        playButton                 matlab.ui.control.Button
        writeButton                matlab.ui.control.Button
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.NumericEditField
        cutoff_freqEditFieldLabel  matlab.ui.control.Label
        cutoff_freqEditField       matlab.ui.control.NumericEditField
        outputnameEditFieldLabel   matlab.ui.control.Label
        outputnameEditField        matlab.ui.control.EditField
        filenameTextAreaLabel      matlab.ui.control.Label
        filenameTextArea           matlab.ui.control.TextArea
        choosefileButton           matlab.ui.control.Button
        noiseEditFieldLabel        matlab.ui.control.Label
        noiseEditField             matlab.ui.control.NumericEditField
        filterfunctionButtonGroup  matlab.ui.container.ButtonGroup
        butterButton               matlab.ui.control.RadioButton
        chebyButton                matlab.ui.control.RadioButton
        bessButton                 matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            clear sound;
            text = app.filterfunctionButtonGroup.SelectedObject.Text;
            if text == "butter"
                index = 1;
            elseif text == "cheby"
                index = 2;
            else
                index = 3;
            end
            index
            MainFunction(app.filenameTextArea.Value{1}, app.noiseEditField.Value, index,4, app.cutoff_freqEditField.Value, app.NEditField.Value, 1);
        end

        % Button pushed function: choosefileButton
        function choosefileButtonPushed(app, event)
            [file, path] = uigetfile('*.*');
            app.filenameTextArea.Value = strcat(path, file);
        end

        % Button pushed function: writeButton
        function writeButtonPushed(app, event)
            text = app.filterfunctionButtonGroup.SelectedObject.Text;
            if text == "butter"
                index = 1;
            elseif text == "cheby"
                index = 2;
            else
                index = 3;
            end
            index
            [data, fs] = MainFunction(app.filenameTextArea.Value{1}, app.noiseEditField.Value, index, 4, app.cutoff_freqEditField.Value, app.NEditField.Value);
            audiowrite(app.outputnameEditField.Value, data, fs);
        end

        % Selection changed function: filterfunctionButtonGroup
        function filterfunctionButtonGroupSelectionChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.writeButton = uibutton(app.UIFigure, 'push');
            app.writeButton.ButtonPushedFcn = createCallbackFcn(app, @writeButtonPushed, true);
            app.writeButton.Text = 'write';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 32;
            app.cutoff_freqEditFieldLabel = uilabel(app.UIFigure);
            app.cutoff_freqEditFieldLabel.Text = 'cutoff_freq';
            app.cutoff_freqEditField = uieditfield(app.UIFigure, 'numeric');
            app.cutoff_freqEditField.Value = 50;
            app.outputnameEditFieldLabel = uilabel(app.UIFigure);
            app.outputnameEditFieldLabel.Text = 'output name';
            app.outputnameEditField = uieditfield(app.UIFigure, 'text');
            app.outputnameEditField.Value = 'output.file';
            app.filenameTextAreaLabel = uilabel(app.UIFigure);
            app.filenameTextAreaLabel.Text = 'file name';
            app.filenameTextArea = uitextarea(app.UIFigure);
            app.choosefileButton = uibutton(app.UIFigure, 'push');
            app.choosefileButton.ButtonPushedFcn = createCallbackFcn(app, @choosefileButtonPushed, true);
            app.choosefileButton.Text = 'choose file';
            app.noiseEditFieldLabel = uilabel(app.UIFigure);
            app.noiseEditFieldLabel.Text = 'noise';
            app.noiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.noiseEditField.Value = -5;
            app.filterfunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.filterfunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @filterfunctionButtonGroupSelectionChanged, true);
            app.filterfunctionButtonGroup.Title = 'filter function';
            app.butterButton = uiradiobutton(app.filterfunctionButtonGroup);
            app.butterButton.Text = 'butter';
            app.butterButton.Value = true;
            app.chebyButton = uiradiobutton(app.filterfunctionButtonGroup);
            app.chebyButton.Text = 'cheby';
            app.bessButton = uiradiobutton(app.filterfunctionButtonGroup);
            app.bessButton.Text = 'bess';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Out

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end